classdef MatlabLT12 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        NghimTab                       matlab.ui.container.Tab
        solanlapLabel                  matlab.ui.control.Label
        ktqutchphnhnhthang_2           matlab.ui.control.Label
        ktqutchphnhnhthang             matlab.ui.control.Label
        ketquaLabel                    matlab.ui.control.Label
        SaisEditField                  matlab.ui.control.EditField
        SaisEditFieldLabel             matlab.ui.control.Label
        KhongphnlicuibEditField        matlab.ui.control.EditField
        KhongphnlicuibEditFieldLabel   matlab.ui.control.Label
        KhongphnliuaEditField          matlab.ui.control.EditField
        KhongphnliuaEditFieldLabel     matlab.ui.control.Label
        fpEditField                    matlab.ui.control.EditField
        fpEditFieldLabel               matlab.ui.control.Label
        fxEditField                    matlab.ui.control.EditField
        fxEditFieldLabel               matlab.ui.control.Label
        RUNButton                      matlab.ui.control.Button
        PhngphpButtonGroup             matlab.ui.container.ButtonGroup
        TiptuynButton                  matlab.ui.control.RadioButton
        LpButton                       matlab.ui.control.RadioButton
        ChiaiButton                    matlab.ui.control.RadioButton
        TNHNGHIMLabel                  matlab.ui.control.Label
        HTML_2                         matlab.ui.control.HTML
        UIAxes_5                       matlab.ui.control.UIAxes
        NiSuyTab_2                     matlab.ui.container.Tab
        OUTPUTPanel_2                  matlab.ui.container.Panel
        ResultEditField                matlab.ui.control.EditField
        KtquEditFieldLabel             matlab.ui.control.Label
        KtquathcLabel                  matlab.ui.control.Label
        DaThucResultEditField          matlab.ui.control.EditField
        TinhButton                     matlab.ui.control.Button
        INPUTPanel_2                   matlab.ui.container.Panel
        Image4                         matlab.ui.control.Image
        NhapeEditField                 matlab.ui.control.EditField
        NhpsaiseLabel                  matlab.ui.control.Label
        NhapgiatrinoisuyEditField      matlab.ui.control.NumericEditField
        NhpgitrnisuyEditFieldLabel     matlab.ui.control.Label
        PhuongPhapDropDown             matlab.ui.control.DropDown
        PhngphpDropDownLabel           matlab.ui.control.Label
        NhapmangyEditField             matlab.ui.control.EditField
        NhpmngyEditFieldLabel          matlab.ui.control.Label
        NhapmangxEditField             matlab.ui.control.EditField
        NhpmngxEditFieldLabel          matlab.ui.control.Label
        TNHNISUYLabel_2                matlab.ui.control.Label
        HTML_6                         matlab.ui.control.HTML
        UIAxes_7                       matlab.ui.control.UIAxes
        HiQuyTab                       matlab.ui.container.Tab
        Image4_2                       matlab.ui.control.Image
        RunButton                      matlab.ui.control.Button
        NhpgitrcndonEditField          matlab.ui.control.EditField
        NhpgitrcndonEditFieldLabel     matlab.ui.control.Label
        NhpdliuyEditField              matlab.ui.control.EditField
        NhpdliuyEditFieldLabel_2       matlab.ui.control.Label
        NhpdliuxEditField              matlab.ui.control.EditField
        NhpdliuxEditFieldLabel_2       matlab.ui.control.Label
        ChnphngphphiquyDropDown        matlab.ui.control.DropDown
        ChnphngphphiquyDropDownLabel   matlab.ui.control.Label
        ResultEditField_2              matlab.ui.control.EditField
        KtqudonLabel                   matlab.ui.control.Label
        KtquathcLabel_2                matlab.ui.control.Label
        DaThucResultEditField_2        matlab.ui.control.EditField
        TNHHIQUYLabel                  matlab.ui.control.Label
        HTML_4                         matlab.ui.control.HTML
        UIAxes_4                       matlab.ui.control.UIAxes
        oHmTab                         matlab.ui.container.Tab
        Image3                         matlab.ui.control.Image
        ppxs                           matlab.ui.control.ListBox
        ChnphngphpohmListBoxLabel      matlab.ui.control.Label
        chonsaiso12                    matlab.ui.control.ListBox
        ChngitrsaisListBoxLabel        matlab.ui.control.Label
        Cchnhp2Panel                   matlab.ui.container.Panel
        bacxapxi2                      matlab.ui.control.ListBox
        BcxpxListBox_2Label            matlab.ui.control.Label
        input                          matlab.ui.control.EditField
        NhpgitrcntnhohmEditField_2Label  matlab.ui.control.Label
        ketqua2                        matlab.ui.control.NumericEditField
        KtquButton_2                   matlab.ui.control.Button
        buocnhayh                      matlab.ui.control.EditField
        NhpbchEditFieldLabel           matlab.ui.control.Label
        hamf                           matlab.ui.control.EditField
        NhphmsEditFieldLabel           matlab.ui.control.Label
        Cchnhp1Panel                   matlab.ui.container.Panel
        input1                         matlab.ui.control.EditField
        NhpgitrcntnhohmEditFieldLabel  matlab.ui.control.Label
        bacxapxi1                      matlab.ui.control.ListBox
        BcxpxListBoxLabel              matlab.ui.control.Label
        ketqua1                        matlab.ui.control.NumericEditField
        KtquButton                     matlab.ui.control.Button
        mangy                          matlab.ui.control.EditField
        NhpdliuyEditFieldLabel         matlab.ui.control.Label
        mangx                          matlab.ui.control.EditField
        NhpdliuxEditFieldLabel         matlab.ui.control.Label
        TNHOHMLabel                    matlab.ui.control.Label
        HTML_5                         matlab.ui.control.HTML
        TchPhnTab                      matlab.ui.container.Tab
        OUTPUTPanel_3                  matlab.ui.container.Panel
        Phngphpsimpson38Label          matlab.ui.control.Label
        simpson                        matlab.ui.control.NumericEditField
        PhngphphnhthangLabel           matlab.ui.control.Label
        hinhthangedit                  matlab.ui.control.NumericEditField
        chinhxacedit                   matlab.ui.control.NumericEditField
        TchphnchnhxcLabel              matlab.ui.control.Label
        simpsonedit                    matlab.ui.control.NumericEditField
        Phngphpsimpson18Label          matlab.ui.control.Label
        INPUTPanel_3                   matlab.ui.container.Panel
        Image2                         matlab.ui.control.Image
        nedit                          matlab.ui.control.NumericEditField
        NhpnLabel                      matlab.ui.control.Label
        bedit                          matlab.ui.control.NumericEditField
        NhpbLabel                      matlab.ui.control.Label
        aedit                          matlab.ui.control.NumericEditField
        NhpaLabel                      matlab.ui.control.Label
        fxedit                         matlab.ui.control.EditField
        NhpfxLabel                     matlab.ui.control.Label
        AnswerButton                   matlab.ui.control.Button
        TNHTCHPHNLabel                 matlab.ui.control.Label
        HTML                           matlab.ui.control.HTML
        GiiThiuNhmTab_2                matlab.ui.container.Tab
        Image5                         matlab.ui.control.Image
        ThnhvinLabel_3                 matlab.ui.control.Label
        Label_5                        matlab.ui.control.Label
        NguynnhThinHoLabel             matlab.ui.control.Label
        ThnhvinLabel_2                 matlab.ui.control.Label
        Label_4                        matlab.ui.control.Label
        ThnhvinLabel                   matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        ThkLabel                       matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        TrngnhmLabel                   matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        NguynonAnBnhLabel              matlab.ui.control.Label
        ThiTrngAnLabel                 matlab.ui.control.Label
        NguynHongAnhLabel              matlab.ui.control.Label
        HHoiBoLabel                    matlab.ui.control.Label
        TnnhmLKNLabel                  matlab.ui.control.Label
        MnhmABAB2022Label              matlab.ui.control.Label
        UIAxes_6                       matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
         function Hoiquy(app,f,x_array, y_array)
            fxs = vpa(f, 3);
            app.DaThucResultEditField_2.Value = string(fxs);
            
            predict_value = str2num(app.NhpgitrcndonEditField.Value)
            app.ResultEditField_2.Value = string(f(predict_value));

            cla(app.UIAxes_4, "reset");
            hold(app.UIAxes_4, 'on');
                fplot(app.UIAxes_4, @(x) f(x));
                plot(app.UIAxes_4, x_array, y_array,"hexagram",'MarkerSize', 20);
            hold(app.UIAxes_4, 'off');
         end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function AnswerButtonPushed(app, event)
            
           
        end

        % Callback function
        function chiadoiButtonPushed(app, event)
         
        end

        % Callback function
        function LapButtonPushed(app, event)
         
        end

        % Callback function
        function TiepTuyenButtonPushed(app, event)
            
        end

        % Callback function
        function dothiButtonPushed(app, event)
             
        end

        % Button pushed function: KtquButton
        function KtquButtonPushed(app, event)
              
            try
                 x = app.mangx.Value;
                 x = str2num(x);
                 y = app.mangy.Value;
                 y = str2num(y); 
                 val = app.input1.Value;
                 val = str2num(val);

                  switch app.bacxapxi1.Value
                     case'Bậc 1'
                         switch app.chonsaiso12.Value                 
                             case'O(h)'
                                 switch app.ppxs.Value
                                     case'Xấp xỉ tiến'
                                         result = xxtohb1(x,y,val);                             
                                         app.ketqua1.Value = result;                         
                                     case'Xấp xỉ lùi'
                                         result = xxlohb1(x,y,val);                             
                                         app.ketqua1.Value = result;
                                     otherwise % xấp xỉ trung tâm
                                         msgbox('Không có kết quả, vui lòng chọn oh2 để tính','Error','error');
                                 end
                             otherwise % O(h^2)
                                 switch app.ppxs.Value
                                     case'Xấp xỉ tiến'
                                         result = xxtoh2b1(x,y,val);                             
                                         app.ketqua1.Value = result;                             
                                     case'Xấp xỉ lùi'
                                         result = xxloh2b1(x,y,val);                             
                                         app.ketqua1.Value = result;
                                     otherwise % xấp xỉ trung tâm
                                         result = xxttb1(x,y,val);                             
                                         app.ketqua1.Value = result;                         
                                 end                                 
                         end
                     otherwise % Bậc 2
                         switch app.chonsaiso12.Value                 
                             case'O(h)'
                                 switch app.ppxs.Value
                                     case'Xấp xỉ tiến'
                                         result = xxtohb2(x,y,val);                             
                                         app.ketqua1.Value = result;                         
                                     case'Xấp xỉ lùi'
                                         result = xxlohb2(x,y,val);                             
                                         app.ketqua1.Value = result;
                                     otherwise % xấp xỉ trung tâm
                                         msgbox('Không có kết quả, vui lòng chọn oh2 để tính','Error','error');
                                 end
                             otherwise % O(h^2)
                                 switch app.ppxs.Value
                                     case'Xấp xỉ tiến'
                                         result = xxtoh2b2(x,y,val);                             
                                         app.ketqua1.Value = result;                             
                                     case'Xấp xỉ lùi'
                                         result = xxloh2b2(x,y,val);                             
                                         app.ketqua1.Value = result;
                                     otherwise % xấp xỉ trung tâm
                                         result = xxttb2(x,y,val);                             
                                         app.ketqua1.Value = result;                         
                                 end                                 
                         end
                         
                 end
            catch error
                switch(error.identifier)
                    case'MATLAB:unassignedOutputs' 
                        msgbox('Chưa nhập đủ dữ liệu','Error','error');
                    case'MATLAB:badsubscript' 
                        msgbox('Chưa nhập đủ dữ liệu','Error','error');
                    otherwise
                        msgbox({error.identifier,error.message},'Error','error');
                end
            end
        end

        % Button pushed function: KtquButton_2
        function KtquButton_2Pushed(app, event)
               
            try
             f = app.hamf.Value;
             f = str2func(['@(x)' f]); 
             h  = app.buocnhayh.Value;
             h  = str2num(h);
             val = app.input.Value;
             val = str2num(val);                      
                 switch app.bacxapxi2.Value
                     case'Bậc 1'
                         switch app.chonsaiso12.Value
                             case'O(h)'
                                 switch app.ppxs.Value
                                     case'Xấp xỉ tiến'
                                         result = xapxitienohb1(f,val,h);                             
                                         app.ketqua2.Value = result;                         
                                     case'Xấp xỉ lùi'
                                         result = xapxiluiohb1(f,val,h);                             
                                         app.ketqua2.Value = result;
                                     otherwise % xấp xỉ trung tâm
                                         msgbox('Không có kết quả,  vui lòng chọn oh2 để tính','Error','error');
                                 end
                             otherwise % O(h^2)
                                 switch app.ppxs.Value
                                     case'Xấp xỉ tiến'
                                         result = xapxitienoh2b1(f,val,h);                             
                                         app.ketqua2.Value = result;                             
                                     case'Xấp xỉ lùi'
                                         result = xapxiluioh2b1(f,val,h);                             
                                         app.ketqua2.Value = result;
                                     otherwise % xấp xỉ trung tâm
                                         result = xapxitrungtamb1(f,val,h);                             
                                         app.ketqua2.Value = result;                         
                                 end                                 
                         end
                     otherwise % Bậc 2
                         switch app.chonsaiso12.Value                 
                             case'O(h)'
                                 switch app.ppxs.Value
                                     case'Xấp xỉ tiến'
                                         result = xapxitienohb2(f,val,h);                             
                                         app.ketqua2.Value = result;                         
                                     case'Xấp xỉ lùi'
                                         result = xapxiluiohb2(f,val,h);                             
                                         app.ketqua2.Value = result;
                                     otherwise % xấp xỉ trung tâm
                                         msgbox('Sai số cắt cụt không phù hợp, vui lòng chọn oh2 ','Error','error');
                                 end
                             otherwise % O(h^2)
                                 switch app.ppxs.Value
                                     case'Xấp xỉ tiến'
                                         result = xapxitienoh2b2(f,val,h);                             
                                         app.ketqua2.Value = result;                             
                                     case'Xấp xỉ lùi'
                                         result = xapxiluioh2b2(f,val,h);                             
                                         app.ketqua2.Value = result;
                                     otherwise % xấp xỉ trung tâm
                                         result = xapxitrungtamb2(f,val,h);                             
                                         app.ketqua2.Value = result;                         
                                 end                                 
                         end
                 end
             catch error
                  switch(error.identifier)
                     case'MATLAB:unassignedOutputs' 
                        msgbox('Chưa nhập bước h hoặc giá trị cần tính đạo hàm.','Error','error');   
                    otherwise
                        msgbox({error.identifier,error.message},'Error','error');
                  end                 
             end
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
             try 
                f = app.fxEditField.Value;
                f = str2func(['@(x)', f]); 
                fp = app.fpEditField.Value;
                fp = str2func(['@(x)', fp]);
                a = app.KhongphnliuaEditField.Value;
                a = str2num(a)
                b = app.KhongphnlicuibEditField.Value;
                b = str2num(b)
                saiso= app.SaisEditField.Value;
                saiso = str2num(saiso)
                m = linspace(a,b,20);
              
                    if app.ChiaiButton.Value==1
                        [nghiem,solanlap] = chiadoi(f,a,b,saiso)
                        app.ketquaLabel.Text = num2str (nghiem);
                        app.solanlapLabel.Text = num2str (solanlap);
                        plot(app.UIAxes_5,m,f(m));
                    end
                    if  app.LpButton.Value ==1
                        [nghiem,solanlap] = lap(fp,a,b,saiso)
                        app.ketquaLabel.Text = num2str (nghiem);
                        app.solanlapLabel.Text = num2str (solanlap);
                        plot(app.UIAxes_5,m,fp(m));
                    end
                    if app.TiptuynButton.Value==1
                        [nghiem,solanlap] = tieptuyen(f,a,b,saiso)
                        app.ketquaLabel.Text = num2str(nghiem);
                        app.solanlapLabel.Text = num2str(solanlap);
                        plot(app.UIAxes_5,m,f(m));
                    end    
                
                catch error
                switch(error.identifier)
                    case 'MATLAB:UndefinedFunction'
                        msgbox('Nhap ham mot bien!','Error','error');
                    case 'MATLAB:m_missing_operator'
                        msgbox('Sai dinh dang ham, vui long nhap lai','Error','error');
                    case'MATLAB:mpower:notScalarAndSquareMatrix'
                        a = sprintf('Loi nhan ma tran!\nDe nhan tung phan tu, dung dau "." truoc lenh.')
                        msgbox(a,'Error','error');
                    otherwise
                        msgbox({error.identifier, error.message},'Error','error');
                end
            end
        end

        % Button pushed function: TinhButton
        function TinhButtonPushed(app, event)
             x_array=str2num(app.NhapmangxEditField.Value);
             y_array=str2num(app.NhapmangyEditField.Value);
             m = str2num(app.NhapeEditField.Value);
%             s1 = 'lagrange';
%             s2 = 'newton';
% 
%            if strcmp(app.PhuongPhapDropDown.Value,s1) == 0
        switch app.PhuongPhapDropDown.Value
            case 'lagrange'
               y1=lagrange(app,x_array,y_array);
               disp(y1);
               app.DaThucResultEditField.Value=string(vpa(y1));
                  
               fx1 = str2func(['@(x)' char(y1)]);
               b= fx1(app.NhapgiatrinoisuyEditField.Value);
               app.ResultEditField.Value=num2str(b); 

               fplot(app.UIAxes_7,@(x_array) fx1(x_array)); 
            case 'newton'
%                m = 2;

               syms x;
               y = newton(app,x,m,x_array,y_array);
               app.DaThucResultEditField.Value=string(vpa(y));

               fx1 = str2func(['@(x)' char(y)]);
               b= fx1(app.NhapgiatrinoisuyEditField.Value);
               app.ResultEditField.Value=num2str(b);  

               fplot(app.UIAxes_7,@(x_array) fx1(x_array)); 
        end
        if app.NhapeEditField.Value == ""
                msgbox('Vui lòng nhập bậc cần nội suy','Errol','errol')
        end
        end

        % Drop down opening function: PhuongPhapDropDown
        function PhuongPhapDropDownOpening(app, event)
            value = app.PhuongPhapDropDown.Value;
            if value == "newton"
                app.NhapeEditField.Visible = "on";
                app.NhpsaiseLabel.Visible = "on";
            else
                app.NhapeEditField.Visible = "off";
                app.NhpsaiseLabel.Visible = "off";
            end
        end

        % Button pushed function: AnswerButton
        function AnswerButtonPushed2(app, event)
           a = app.aedit.Value;
b= app.bedit.Value;
n= app.nedit.Value;
fx= app.fxedit.Value;
fx = str2func(['@(x)' fx]);
format long;
app.hinhthangedit.Value = tichphanhinhthang(fx,a,b,n);
app.simpsonedit.Value = tichphanSimpson18(fx,a,b,n);
app.simpson.Value= tichphanSimpson38(fx,a,b,n);
app.chinhxacedit.Value =integral(fx,a,b);
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            try 
                x_array = str2num(app.NhpdliuyEditField.Value);
                y_array = str2num(app.NhpdliuxEditField.Value);
                value =0;
                switch app.ChnphngphphiquyDropDown.Value
                    case "Tuyến tính"
                        if(strcmp(results = checkFunction(app,value,x_array,y_array))==0)
                            [a0, a1] = hoiquytuyentinh(app, x_array, y_array);
                          % f = @(x) a0 + (a1 .* x);
                        end
                    case "Hàm mũ"
                        if(strcmp(results = checkFunction(app,value,x_array,y_array))==0)
                            [a, b] = hoiquyhammu(app, x_array, y_array);
                           % f = @(x) a * x .^ b;   
                        end
                    case "Logarit"
                        if(strcmp(results = checkFunction(app,value,x_array,y_array))==0)
                            [a, b] = hoiquyhamlogarit(app, x_array, y_array);
                           % f = @(x) a * exp(b .* x);  
                        end
                end
                Hoiquy(app,f,x_array, y_array);
             
           catch error
               switch (error.identifier)
                otherwise
                    msgbox({error.identifier,error.message},'Error','error');
                end
           end
        end

        % Drop down opening function: ChnphngphphiquyDropDown
        function ChnphngphphiquyDropDownOpening(app, event)
            
        end

        % Value changed function: NhpdliuxEditField
        function NhpdliuxEditFieldValueChanged(app, event)
            value = app.NhpdliuxEditField.Value;
        end

        % Value changed function: ChnphngphphiquyDropDown
        function ChnphngphphiquyDropDownValueChanged(app, event)
    %        value = app.ChnphngphphiquyDropDown.Value;
            switch app.ChnphngphphiquyDropDown.Value 
                case "Tuyến tính"
                    app.NhpdliuxEditField.Value ="";
                    app.NhpdliuyEditField.Value ="";
                    app.NhpgitrcndonEditField.Value ="";
                    app.NhpdliuxEditField.Placeholder='Ex: [6 7 11 15 17 21 23 29 29 37 39]';
                    app.NhpdliuyEditField.Placeholder ="Ex: [29 21 29 14 21 15 7 7 13 0 3]";
                    app.NhpgitrcndonEditField.Placeholder ="Ex: 2";
                case 'Hàm mũ'
                    app.NhpdliuxEditField.Value ="";
                    app.NhpdliuyEditField.Value ="";
                    app.NhpgitrcndonEditField.Value ="";
                    app.NhpdliuxEditField.Placeholder='Ex: [1 2 3 4 5]';
                    app.NhpdliuyEditField.Placeholder ="Ex: [0.5 1.7 3.4 5.7 8.4]";
                    app.NhpgitrcndonEditField.Placeholder ="Ex: 2";
                case 'Logarit'
                    app.NhpdliuxEditField.Value ="";
                    app.NhpdliuyEditField.Value ="";
                    app.NhpgitrcndonEditField.Value ="";
                    app.NhpdliuxEditField.Placeholder='Ex: [0.65 0.75 0.85 0.95 1.15]';
                    app.NhpdliuyEditField.Placeholder ="Ex: [0.96 1.06 1.17 1.29 1.58]";
                    app.NhpgitrcndonEditField.Placeholder ="Ex: 2";
                otherwise
                    app.NhpdliuxEditField.Value ="";
                    app.NhpdliuyEditField.Value ="";
                    app.NhpgitrcndonEditField.Value ="";
                    app.NhpdliuxEditField.Placeholder='Please input x_array';
                    app.NhpdliuyEditField.Placeholder ='Please input y_array';
                    app.NhpgitrcndonEditField.Placeholder ='Please input predict';

            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_6 = uiaxes(app.UIFigure);
            title(app.UIAxes_6, 'Đồ thị hàm số nội suy')
            xlabel(app.UIAxes_6, 'X')
            ylabel(app.UIAxes_6, 'Y')
            zlabel(app.UIAxes_6, 'Z')
            app.TabGroup = uitabgroup(app.UIFigure);
            app.NghimTab = uitab(app.TabGroup);
            app.NghimTab.Title = 'Nghiệm';
            app.UIAxes_5 = uiaxes(app.NghimTab);
            title(app.UIAxes_5, 'Đồ thị f(x)')
            xlabel(app.UIAxes_5, 'x')
            ylabel(app.UIAxes_5, 'y')
            app.HTML_2 = uihtml(app.NghimTab);
            app.HTML_2.HTMLSource = '<!DOCTYPE html><html>    <body style="background-color:#6C9DC4;"></body></html>';
            app.TNHNGHIMLabel = uilabel(app.NghimTab);
            app.TNHNGHIMLabel.Text = 'TÍNH NGHIỆM';
            app.PhngphpButtonGroup = uibuttongroup(app.NghimTab);
            app.PhngphpButtonGroup.Title = 'Phương pháp:';
            app.ChiaiButton = uiradiobutton(app.PhngphpButtonGroup);
            app.ChiaiButton.Text = 'Chia đôi';
            app.ChiaiButton.Value = true;
            app.LpButton = uiradiobutton(app.PhngphpButtonGroup);
            app.LpButton.Text = 'Lặp';
            app.TiptuynButton = uiradiobutton(app.PhngphpButtonGroup);
            app.TiptuynButton.Text = 'Tiếp tuyến';
            app.RUNButton = uibutton(app.NghimTab, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = {'RUN'; ''};
            app.fxEditFieldLabel = uilabel(app.NghimTab);
            app.fxEditFieldLabel.Text = 'f(x):';
            app.fxEditField = uieditfield(app.NghimTab, 'text');
            app.fxEditField.Value = '3*x.^3-8*x.^2-20*x+16';
            app.fpEditFieldLabel = uilabel(app.NghimTab);
            app.fpEditFieldLabel.Text = 'fp:';
            app.fpEditField = uieditfield(app.NghimTab, 'text');
            app.fpEditField.Value = '(3*x.^3-8*x.^2+16)/20';
            app.KhongphnliuaEditFieldLabel = uilabel(app.NghimTab);
            app.KhongphnliuaEditFieldLabel.Text = 'Khoảng phân li đầu (a):';
            app.KhongphnliuaEditField = uieditfield(app.NghimTab, 'text');
            app.KhongphnliuaEditField.Value = '0.2';
            app.KhongphnlicuibEditFieldLabel = uilabel(app.NghimTab);
            app.KhongphnlicuibEditFieldLabel.Text = 'Khoảng phân li cuối (b):';
            app.KhongphnlicuibEditField = uieditfield(app.NghimTab, 'text');
            app.KhongphnlicuibEditField.Value = '1';
            app.SaisEditFieldLabel = uilabel(app.NghimTab);
            app.SaisEditFieldLabel.Text = 'Sai số:';
            app.SaisEditField = uieditfield(app.NghimTab, 'text');
            app.SaisEditField.Value = '0.005';
            app.ketquaLabel = uilabel(app.NghimTab);
            app.ketquaLabel.Text = 'ketqua';
            app.ktqutchphnhnhthang = uilabel(app.NghimTab);
            app.ktqutchphnhnhthang.Text = 'Kết Quả Nghiệm:';
            app.ktqutchphnhnhthang_2 = uilabel(app.NghimTab);
            app.ktqutchphnhnhthang_2.Text = 'Số Lần Lặp:';
            app.solanlapLabel = uilabel(app.NghimTab);
            app.solanlapLabel.Text = 'solanlap';
            app.NiSuyTab_2 = uitab(app.TabGroup);
            app.NiSuyTab_2.Title = 'Nội Suy';
            app.UIAxes_7 = uiaxes(app.NiSuyTab_2);
            title(app.UIAxes_7, 'Đồ thị hàm số nội suy')
            xlabel(app.UIAxes_7, 'X')
            ylabel(app.UIAxes_7, 'Y')
            zlabel(app.UIAxes_7, 'Z')
            app.HTML_6 = uihtml(app.NiSuyTab_2);
            app.HTML_6.HTMLSource = '<!DOCTYPE html><html>    <body style="background-color:#6C9DC4;"></body></html>';
            app.TNHNISUYLabel_2 = uilabel(app.NiSuyTab_2);
            app.TNHNISUYLabel_2.Text = 'TÍNH NỘI SUY';
            app.INPUTPanel_2 = uipanel(app.NiSuyTab_2);
            app.INPUTPanel_2.Title = 'INPUT';
            app.NhpmngxEditFieldLabel = uilabel(app.INPUTPanel_2);
            app.NhpmngxEditFieldLabel.Text = 'Nhập mảng x';
            app.NhapmangxEditField = uieditfield(app.INPUTPanel_2, 'text');
            app.NhapmangxEditField.Value = '[0.1 0.2 0.3 0.4]';
            app.NhpmngyEditFieldLabel = uilabel(app.INPUTPanel_2);
            app.NhpmngyEditFieldLabel.Text = 'Nhập mảng y';
            app.NhapmangyEditField = uieditfield(app.INPUTPanel_2, 'text');
            app.NhapmangyEditField.Value = '[0.09983  0.19867  0.29552  0.38942]';
            app.PhngphpDropDownLabel = uilabel(app.INPUTPanel_2);
            app.PhngphpDropDownLabel.Text = 'Phương pháp';
            app.PhuongPhapDropDown = uidropdown(app.INPUTPanel_2);
            app.PhuongPhapDropDown.Items = {'lagrange', 'newton'};
            app.PhuongPhapDropDown.DropDownOpeningFcn = createCallbackFcn(app, @PhuongPhapDropDownOpening, true);
            app.PhuongPhapDropDown.Value = 'lagrange';
            app.NhpgitrnisuyEditFieldLabel = uilabel(app.INPUTPanel_2);
            app.NhpgitrnisuyEditFieldLabel.Text = 'Nhập giá trị nội suy';
            app.NhapgiatrinoisuyEditField = uieditfield(app.INPUTPanel_2, 'numeric');
            app.NhapgiatrinoisuyEditField.Value = 2;
            app.NhpsaiseLabel = uilabel(app.INPUTPanel_2);
            app.NhpsaiseLabel.Text = 'Nhập sai số e';
            app.NhapeEditField = uieditfield(app.INPUTPanel_2, 'text');
            app.NhapeEditField.Value = '1';
            app.Image4 = uiimage(app.INPUTPanel_2);
            app.Image4.ImageSource = 'KHTN.png';
            app.OUTPUTPanel_2 = uipanel(app.NiSuyTab_2);
            app.OUTPUTPanel_2.Title = 'OUTPUT';
            app.TinhButton = uibutton(app.OUTPUTPanel_2, 'push');
            app.TinhButton.ButtonPushedFcn = createCallbackFcn(app, @TinhButtonPushed, true);
            app.TinhButton.Text = 'TÍNH';
            app.DaThucResultEditField = uieditfield(app.OUTPUTPanel_2, 'text');
            app.KtquathcLabel = uilabel(app.OUTPUTPanel_2);
            app.KtquathcLabel.Text = 'Kết quả Đa thức';
            app.KtquEditFieldLabel = uilabel(app.OUTPUTPanel_2);
            app.KtquEditFieldLabel.Text = 'Kết quả';
            app.ResultEditField = uieditfield(app.OUTPUTPanel_2, 'text');
            app.HiQuyTab = uitab(app.TabGroup);
            app.HiQuyTab.Title = 'Hồi Quy';
            app.UIAxes_4 = uiaxes(app.HiQuyTab);
            title(app.UIAxes_4, 'Title')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            zlabel(app.UIAxes_4, 'Z')
            app.HTML_4 = uihtml(app.HiQuyTab);
            app.HTML_4.HTMLSource = '<!DOCTYPE html><html>    <body style="background-color:#6C9DC4;"></body></html>';
            app.TNHHIQUYLabel = uilabel(app.HiQuyTab);
            app.TNHHIQUYLabel.Text = 'TÍNH HỒI QUY';
            app.DaThucResultEditField_2 = uieditfield(app.HiQuyTab, 'text');
            app.KtquathcLabel_2 = uilabel(app.HiQuyTab);
            app.KtquathcLabel_2.Text = 'Kết quả Đa thức:';
            app.KtqudonLabel = uilabel(app.HiQuyTab);
            app.KtqudonLabel.Text = 'Kết quả dự đoán:';
            app.ResultEditField_2 = uieditfield(app.HiQuyTab, 'text');
            app.ChnphngphphiquyDropDownLabel = uilabel(app.HiQuyTab);
            app.ChnphngphphiquyDropDownLabel.Text = 'Chọn phương pháp hồi quy:';
            app.ChnphngphphiquyDropDown = uidropdown(app.HiQuyTab);
            app.ChnphngphphiquyDropDown.Items = {'Tuyến tính ', 'Hàm mũ ', 'Logarit'};
            app.ChnphngphphiquyDropDown.DropDownOpeningFcn = createCallbackFcn(app, @ChnphngphphiquyDropDownOpening, true);
            app.ChnphngphphiquyDropDown.ValueChangedFcn = createCallbackFcn(app, @ChnphngphphiquyDropDownValueChanged, true);
            app.ChnphngphphiquyDropDown.Value = 'Tuyến tính ';
            app.NhpdliuxEditFieldLabel_2 = uilabel(app.HiQuyTab);
            app.NhpdliuxEditFieldLabel_2.Text = 'Nhập dữ liệu x';
            app.NhpdliuxEditField = uieditfield(app.HiQuyTab, 'text');
            app.NhpdliuxEditField.ValueChangedFcn = createCallbackFcn(app, @NhpdliuxEditFieldValueChanged, true);
            app.NhpdliuxEditField.Value = '[1 2 3 4 5]';
            app.NhpdliuyEditFieldLabel_2 = uilabel(app.HiQuyTab);
            app.NhpdliuyEditFieldLabel_2.Text = 'Nhập dữ liệu y';
            app.NhpdliuyEditField = uieditfield(app.HiQuyTab, 'text');
            app.NhpdliuyEditField.Value = '[0.5 1.7 3.4 5.7 8.4]';
            app.NhpgitrcndonEditFieldLabel = uilabel(app.HiQuyTab);
            app.NhpgitrcndonEditFieldLabel.Text = 'Nhập giá trị cần dự đoán:';
            app.NhpgitrcndonEditField = uieditfield(app.HiQuyTab, 'text');
            app.NhpgitrcndonEditField.Value = '7';
            app.RunButton = uibutton(app.HiQuyTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.Image4_2 = uiimage(app.HiQuyTab);
            app.Image4_2.ImageSource = 'KHTN.png';
            app.oHmTab = uitab(app.TabGroup);
            app.oHmTab.Title = 'Đạo Hàm';
            app.HTML_5 = uihtml(app.oHmTab);
            app.HTML_5.HTMLSource = '<!DOCTYPE html><html>    <body style="background-color:#6C9DC4;"></body></html>';
            app.TNHOHMLabel = uilabel(app.oHmTab);
            app.TNHOHMLabel.Text = 'TÍNH ĐẠO HÀM';
            app.Cchnhp1Panel = uipanel(app.oHmTab);
            app.Cchnhp1Panel.Title = 'Cách nhập 1';
            app.NhpdliuxEditFieldLabel = uilabel(app.Cchnhp1Panel);
            app.NhpdliuxEditFieldLabel.Text = 'Nhập dữ liệu x';
            app.mangx = uieditfield(app.Cchnhp1Panel, 'text');
            app.mangx.Value = '[-1 -0.5 0 0.5 1 1.5 2]';
            app.NhpdliuyEditFieldLabel = uilabel(app.Cchnhp1Panel);
            app.NhpdliuyEditFieldLabel.Text = 'Nhập dữ liệu y';
            app.mangy = uieditfield(app.Cchnhp1Panel, 'text');
            app.mangy.Value = '[1.05 1.2625 1.25 0.975 0.25 -1.2625 -4.05]';
            app.KtquButton = uibutton(app.Cchnhp1Panel, 'push');
            app.KtquButton.ButtonPushedFcn = createCallbackFcn(app, @KtquButtonPushed, true);
            app.KtquButton.Text = 'Kết quả';
            app.ketqua1 = uieditfield(app.Cchnhp1Panel, 'numeric');
            app.BcxpxListBoxLabel = uilabel(app.Cchnhp1Panel);
            app.BcxpxListBoxLabel.Text = 'Bậc xấp xỉ';
            app.bacxapxi1 = uilistbox(app.Cchnhp1Panel);
            app.bacxapxi1.Items = {'Bậc 1', 'Bậc 2', ''};
            app.bacxapxi1.Value = 'Bậc 1';
            app.NhpgitrcntnhohmEditFieldLabel = uilabel(app.Cchnhp1Panel);
            app.NhpgitrcntnhohmEditFieldLabel.Text = 'Nhập giá trị cần tính đạo hàm';
            app.input1 = uieditfield(app.Cchnhp1Panel, 'text');
            app.input1.Value = '0.5';
            app.Cchnhp2Panel = uipanel(app.oHmTab);
            app.Cchnhp2Panel.Title = 'Cách nhập 2';
            app.NhphmsEditFieldLabel = uilabel(app.Cchnhp2Panel);
            app.NhphmsEditFieldLabel.Text = 'Nhập hàm số';
            app.hamf = uieditfield(app.Cchnhp2Panel, 'text');
            app.hamf.Value = '-0.1*x.^4-0.15*x.^3-0.5*x.^2-0.25*x+1.25';
            app.NhpbchEditFieldLabel = uilabel(app.Cchnhp2Panel);
            app.NhpbchEditFieldLabel.Text = 'Nhập bước h';
            app.buocnhayh = uieditfield(app.Cchnhp2Panel, 'text');
            app.buocnhayh.Value = '0.5';
            app.KtquButton_2 = uibutton(app.Cchnhp2Panel, 'push');
            app.KtquButton_2.ButtonPushedFcn = createCallbackFcn(app, @KtquButton_2Pushed, true);
            app.KtquButton_2.Text = 'Kết quả';
            app.ketqua2 = uieditfield(app.Cchnhp2Panel, 'numeric');
            app.NhpgitrcntnhohmEditField_2Label = uilabel(app.Cchnhp2Panel);
            app.NhpgitrcntnhohmEditField_2Label.Text = 'Nhập giá trị cần tính đạo hàm';
            app.input = uieditfield(app.Cchnhp2Panel, 'text');
            app.input.Value = '0.5';
            app.BcxpxListBox_2Label = uilabel(app.Cchnhp2Panel);
            app.BcxpxListBox_2Label.Text = 'Bậc xấp xỉ ';
            app.bacxapxi2 = uilistbox(app.Cchnhp2Panel);
            app.bacxapxi2.Items = {'Bậc 1', 'Bậc 2', ''};
            app.bacxapxi2.Value = 'Bậc 1';
            app.ChngitrsaisListBoxLabel = uilabel(app.oHmTab);
            app.ChngitrsaisListBoxLabel.Text = 'Chọn giá trị sai số';
            app.chonsaiso12 = uilistbox(app.oHmTab);
            app.chonsaiso12.Items = {'O(h)', 'O(h^2)', ''};
            app.chonsaiso12.Value = 'O(h)';
            app.ChnphngphpohmListBoxLabel = uilabel(app.oHmTab);
            app.ChnphngphpohmListBoxLabel.Text = 'Chọn phương pháp đạo hàm';
            app.ppxs = uilistbox(app.oHmTab);
            app.ppxs.Items = {'Xấp xỉ tiến', 'Xấp xỉ lùi', 'Xấp xỉ trung tâm'};
            app.ppxs.Value = 'Xấp xỉ tiến';
            app.Image3 = uiimage(app.oHmTab);
            app.Image3.ImageSource = 'KHTN.png';
            app.TchPhnTab = uitab(app.TabGroup);
            app.TchPhnTab.Title = 'Tích Phân';
            app.HTML = uihtml(app.TchPhnTab);
            app.HTML.HTMLSource = '<!DOCTYPE html><html>    <body style="background-color:#6C9DC4;"></body></html>';
            app.TNHTCHPHNLabel = uilabel(app.TchPhnTab);
            app.TNHTCHPHNLabel.Text = 'TÍNH TÍCH PHÂN';
            app.INPUTPanel_3 = uipanel(app.TchPhnTab);
            app.INPUTPanel_3.Title = 'INPUT';
            app.AnswerButton = uibutton(app.INPUTPanel_3, 'push');
            app.AnswerButton.ButtonPushedFcn = createCallbackFcn(app, @AnswerButtonPushed2, true);
            app.AnswerButton.Text = 'Answer';
            app.NhpfxLabel = uilabel(app.INPUTPanel_3);
            app.NhpfxLabel.Text = 'Nhập f(x):';
            app.fxedit = uieditfield(app.INPUTPanel_3, 'text');
            app.fxedit.Value = 'x.^3.*sin(x)';
            app.NhpaLabel = uilabel(app.INPUTPanel_3);
            app.NhpaLabel.Text = 'Nhập a:';
            app.aedit = uieditfield(app.INPUTPanel_3, 'numeric');
            app.NhpbLabel = uilabel(app.INPUTPanel_3);
            app.NhpbLabel.Text = 'Nhập b:';
            app.bedit = uieditfield(app.INPUTPanel_3, 'numeric');
            app.bedit.Value = 1;
            app.NhpnLabel = uilabel(app.INPUTPanel_3);
            app.NhpnLabel.Text = 'Nhập n:';
            app.nedit = uieditfield(app.INPUTPanel_3, 'numeric');
            app.nedit.Value = 10;
            app.Image2 = uiimage(app.INPUTPanel_3);
            app.Image2.ImageSource = 'KHTN.png';
            app.OUTPUTPanel_3 = uipanel(app.TchPhnTab);
            app.OUTPUTPanel_3.Title = 'OUTPUT';
            app.Phngphpsimpson18Label = uilabel(app.OUTPUTPanel_3);
            app.Phngphpsimpson18Label.Text = {'Phương pháp simpson1/8:'; ''};
            app.simpsonedit = uieditfield(app.OUTPUTPanel_3, 'numeric');
            app.simpsonedit.ValueDisplayFormat = '%.10f';
            app.TchphnchnhxcLabel = uilabel(app.OUTPUTPanel_3);
            app.TchphnchnhxcLabel.Text = 'Tích phân chính xác:';
            app.chinhxacedit = uieditfield(app.OUTPUTPanel_3, 'numeric');
            app.chinhxacedit.ValueDisplayFormat = '%.10f';
            app.hinhthangedit = uieditfield(app.OUTPUTPanel_3, 'numeric');
            app.hinhthangedit.ValueDisplayFormat = '%.10f';
            app.PhngphphnhthangLabel = uilabel(app.OUTPUTPanel_3);
            app.PhngphphnhthangLabel.Text = 'Phương pháp hình thang:';
            app.simpson = uieditfield(app.OUTPUTPanel_3, 'numeric');
            app.simpson.ValueDisplayFormat = '%.10f';
            app.Phngphpsimpson38Label = uilabel(app.OUTPUTPanel_3);
            app.Phngphpsimpson38Label.Text = 'Phương pháp simpson3/8:';
            app.GiiThiuNhmTab_2 = uitab(app.TabGroup);
            app.GiiThiuNhmTab_2.Title = 'Giới Thiệu Nhóm';
            app.MnhmABAB2022Label = uilabel(app.GiiThiuNhmTab_2);
            app.MnhmABAB2022Label.Text = 'Mã nhóm: ABAB2022';
            app.TnnhmLKNLabel = uilabel(app.GiiThiuNhmTab_2);
            app.TnnhmLKNLabel.Text = 'Tên nhóm: LKN';
            app.HHoiBoLabel = uilabel(app.GiiThiuNhmTab_2);
            app.HHoiBoLabel.Text = 'Hà Hoài Bảo';
            app.NguynHongAnhLabel = uilabel(app.GiiThiuNhmTab_2);
            app.NguynHongAnhLabel.Text = 'Nguyễn Hoàng Anh';
            app.ThiTrngAnLabel = uilabel(app.GiiThiuNhmTab_2);
            app.ThiTrngAnLabel.Text = 'Thái Trường An';
            app.NguynonAnBnhLabel = uilabel(app.GiiThiuNhmTab_2);
            app.NguynonAnBnhLabel.Text = 'Nguyễn Đoàn An Bình';
            app.Label = uilabel(app.GiiThiuNhmTab_2);
            app.Label.Text = '20200139';
            app.TrngnhmLabel = uilabel(app.GiiThiuNhmTab_2);
            app.TrngnhmLabel.Text = 'Trưởng nhóm';
            app.Label_2 = uilabel(app.GiiThiuNhmTab_2);
            app.Label_2.Text = '20200139';
            app.ThkLabel = uilabel(app.GiiThiuNhmTab_2);
            app.ThkLabel.Text = 'Thư ký';
            app.Label_3 = uilabel(app.GiiThiuNhmTab_2);
            app.Label_3.Text = '20200128';
            app.ThnhvinLabel = uilabel(app.GiiThiuNhmTab_2);
            app.ThnhvinLabel.Text = 'Thành viên';
            app.Label_4 = uilabel(app.GiiThiuNhmTab_2);
            app.Label_4.Text = '20200007';
            app.ThnhvinLabel_2 = uilabel(app.GiiThiuNhmTab_2);
            app.ThnhvinLabel_2.Text = 'Thành viên';
            app.NguynnhThinHoLabel = uilabel(app.GiiThiuNhmTab_2);
            app.NguynnhThinHoLabel.Text = 'Nguyễn ĐìnhThiên Hào';
            app.Label_5 = uilabel(app.GiiThiuNhmTab_2);
            app.Label_5.Text = '20200185';
            app.ThnhvinLabel_3 = uilabel(app.GiiThiuNhmTab_2);
            app.ThnhvinLabel_3.Text = 'Thành viên';
            app.Image5 = uiimage(app.GiiThiuNhmTab_2);
            app.Image5.ImageSource = 'Gioi_thieu_nhom.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MatlabLT12

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end